classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ProtocolTab                     matlab.ui.container.Tab
        HoldingtimemsEditFieldLabel     matlab.ui.control.Label
        HoldingtimemsEditField          matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField     matlab.ui.control.NumericEditField
        ClampingvoltageminmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltageminmVEditField   matlab.ui.control.NumericEditField
        ClampingtimemsEditFieldLabel    matlab.ui.control.Label
        ClampingtimemsEditField         matlab.ui.control.NumericEditField
        ProtocolVisualizationUpdateButton  matlab.ui.control.Button
        NernstpotentialEkmVLabel        matlab.ui.control.Label
        NernstpotentialEkmVEditField    matlab.ui.control.NumericEditField
        DatauploadButton                matlab.ui.control.Button
        ClampingvoltagestepmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagestepmVEditField  matlab.ui.control.NumericEditField
        ClampingvoltagemaxmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagemaxmVEditField   matlab.ui.control.NumericEditField
        ModelCalibrationButton          matlab.ui.control.Button
        UIAxesTopLeft                   matlab.ui.control.UIAxes
        UIAxesBottomLeft                matlab.ui.control.UIAxes
        IKtoKv42Tab                     matlab.ui.container.Tab
        TextArea_1                      matlab.ui.control.TextArea
        HighlightShowEquationsButton    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        fKtoKnobLabel                   matlab.ui.control.Label
        fKtoKnob                        matlab.ui.control.Knob
        GKtoKnobLabel                   matlab.ui.control.Label
        GKtoKnob                        matlab.ui.control.Knob
        GKtopKnobLabel                  matlab.ui.control.Label
        GKtopKnob                       matlab.ui.control.Knob
        p1Knob_6Label                   matlab.ui.control.Label
        p1Knob_1                        matlab.ui.control.Knob
        p2Knob_6Label                   matlab.ui.control.Label
        p2Knob_1                        matlab.ui.control.Knob
        p3Knob_6Label                   matlab.ui.control.Label
        p3Knob_1                        matlab.ui.control.Knob
        p4Knob_6Label                   matlab.ui.control.Label
        p4Knob_1                        matlab.ui.control.Knob
        p5Knob_6Label                   matlab.ui.control.Label
        p5Knob_1                        matlab.ui.control.Knob
        p6Knob_5Label                   matlab.ui.control.Label
        p6Knob_1                        matlab.ui.control.Knob
        p7Knob_5Label                   matlab.ui.control.Label
        p7Knob_1                        matlab.ui.control.Knob
        p8Knob_5Label                   matlab.ui.control.Label
        p8Knob_1                        matlab.ui.control.Knob
        p9Knob_5Label                   matlab.ui.control.Label
        p9Knob_1                        matlab.ui.control.Knob
        p10KnobLabel                    matlab.ui.control.Label
        p10Knob_1                       matlab.ui.control.Knob
        p11KnobLabel                    matlab.ui.control.Label
        p11Knob_1                       matlab.ui.control.Knob
        p12KnobLabel                    matlab.ui.control.Label
        p12Knob_1                       matlab.ui.control.Knob
        p13KnobLabel                    matlab.ui.control.Label
        p13Knob_1                       matlab.ui.control.Knob
        p14KnobLabel                    matlab.ui.control.Label
        p14Knob_1                       matlab.ui.control.Knob
        IKslow1Kv15Tab                  matlab.ui.container.Tab
        TextArea_4                      matlab.ui.control.TextArea
        HighlightShowEquationsButton_4  matlab.ui.control.Button
        ResetButton_4                   matlab.ui.control.Button
        IKslow2Kv21Tab                  matlab.ui.container.Tab
        IKssTab                         matlab.ui.container.Tab
        ResetButton_5                   matlab.ui.control.Button
        HightlightButton_5              matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        SelectVoltageDropDownLabel      matlab.ui.control.Label
        SelectVoltageDropDown           matlab.ui.control.DropDown
        UIAxesMiddleRight               matlab.ui.control.UIAxes
        UIAxesTopRight                  matlab.ui.control.UIAxes
        UIAxesBottomRight               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % time space
        t % entire time
        hold_t % holding time
        pulse_t % voltage-clamping time
        
        % voltages
        volts
        num_volts
        volt_idx
        
        % currents
        yksum_set
        ykto_set

        % apps for dialog windows
        KtoDialogApp
        
        % warning window
        WarnApp
        
        % default parameter values
        Kto_default
        Kss_default
        
        % experimental data
        exp_t
        exp_current_set
        exp_idx
        exp_num_volts
    end
    
    properties (Access = public)
        % For IKtof dialog box
        p1Kto = 33; 
        p2Kto = 15.5; 
        p3Kto = 20; 
        p4Kto = 16; 
        p5Kto = 8; 
        p6Kto = 7; 
        p7Kto = 0.03577; 
        p8Kto = 0.06237; 
        p9Kto = 0.18064; 
        p10Kto = 0.3956; 
        p11Kto = 0.000152; 
        p12Kto = 0.067083; 
        p13Kto = 0.00095; 
        p14Kto = 0.051335; 
        fKto = 0.2087704319; 
        GKto = 0.14067; 
        GKtop = 0.387;
    end
    
    methods (Access = private)
        function [t, hold_t, pulse_t] = gen_time_space(app)
            hold_t = 0:app.HoldingtimemsEditField.Value;
            pulse_t = 0:app.ClampingtimemsEditField.Value;
            pulse_t_shift = pulse_t + app.HoldingtimemsEditField.Value + 1;
            t = [hold_t, pulse_t_shift];
        end
        
        function [volts, num_volts] = gen_volts(app)
            minv = app.ClampingvoltageminmVEditField.Value;
            stepv = app.ClampingvoltagestepmVEditField.Value;
            maxv = app.ClampingvoltagemaxmVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function [current_trc] = gen_IKto(app, v)
            param = zeros(17, 1);
            param(1) = app.p1Knob_1.Value;
            param(2) = app.p2Knob_1.Value;
            param(3) = app.p3Knob_1.Value;
            param(4) = app.p4Knob_1.Value;
            param(5) = app.p5Knob_1.Value;
            param(6) = app.p6Knob_1.Value;
            param(7) = app.p7Knob_1.Value;
            param(8) = app.p8Knob_1.Value;
            param(9) = app.p9Knob_1.Value;
            param(10) = app.p10Knob_1.Value;
            param(11) = app.p11Knob_1.Value;
            param(12) = app.p12Knob_1.Value;
            param(13) = app.p13Knob_1.Value;
            param(14) = app.p14Knob_1.Value;
            param(15) = app.fKtoKnob.Value;
            param(16) = app.GKtoKnob.Value;
            param(17) = app.GKtopKnob.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.hold_t;
            tt{3} = app.pulse_t;
            current_trc = IKto(param, app.HoldingpotentialmVEditField.Value, ...
                v, tt, app.NernstpotentialEkmVEditField.Value);
        end

        function [current_trc_set] = gen_currents_over_volts(app, current_generator)
            % wrapper function looping over voltages
            current_trc_set = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                current_trc_set(:,i) = current_generator(app, app.volts(i));
            end
        end
        
        function plot_protocol(app)
            yH = ones(1, length(app.hold_t))*app.HoldingpotentialmVEditField.Value;
            yP = ones(1, length(app.pulse_t))*app.volts(1);
            y = [yH, yP];
            plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesTopLeft, "on")
            for i=2:app.num_volts
                yP = ones(1, length(app.pulse_t))*app.volts(i);
                y = [yH, yP];
                plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesTopLeft, "off")
        end
        
        function plot_sum_currents_allvolts(app)
            % top-right axes; IKsum
            plot(app.UIAxesTopRight, app.t, app.yksum_set(:,1))
            hold(app.UIAxesTopRight, "on")
            for i=2:app.num_volts
                plot(app.UIAxesTopRight, app.t, app.yksum_set(:,i))
            end
            hold(app.UIAxesTopRight, "off")
            axis(app.UIAxesTopRight, "tight")
        end
        
        function plot_currents_onevolt(app, volt_idx, highlight_idx)
            % MiddleRight; individual K+ currents for one voltage
            l(1) = plot(app.UIAxesMiddleRight, app.t, app.ykto_set(:,volt_idx));
            
%             hold(app.UIAxesMiddleRight, "on")
%             l(6) = plot(app.UIAxesMiddleRight, app.t, app.yktos_set(:,volt_idx));
%             l(2) = plot(app.UIAxesMiddleRight, app.t, app.yk1_set(:,volt_idx));
%             l(3) = plot(app.UIAxesMiddleRight, app.t, app.yks_set(:,volt_idx));
%             l(4) = plot(app.UIAxesMiddleRight, app.t, app.ykur_set(:,volt_idx));
%             l(5) = plot(app.UIAxesMiddleRight, app.t, app.ykss_set(:,volt_idx));
%             hold(app.UIAxesMiddleRight, "off")
            
            switch highlight_idx
                case false
                    legend(app.UIAxesMiddleRight, "I_{Kto}","I_{Ktos}","I_{KI}","I_{Ks}","I_{Kur}","I_{Kss}", "Location","northeast")
                case 1
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Ktof}", "Location","northeast")
                case 2
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{KI}", "Location","northeast")
                case 3
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Ks}", "Location","northeast")
                case 4
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kur}", "Location","northeast")
                case 5
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kss}", "Location","northeast")
                case 6
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kto}", "Location","northeast")
            end            
            set(l(highlight_idx), "LineWidth",2)       
            axis(app.UIAxesMiddleRight, "tight")
            
            % IKsum for one voltage
            if isequal(app.exp_idx, true) && isequal(app.num_volts, app.exp_num_volts)
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                hold(app.UIAxesBottomRight, "on")
                plot(app.UIAxesBottomRight, app.exp_t, app.exp_current_set(:,volt_idx), "LineWidth",2, "Color",[0,0,0,0.5])
                hold(app.UIAxesBottomRight, "off")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction","Experimental Data", "Location","northeast")
            elseif app.num_volts ~= app.exp_num_volts
                app.WarnApp = warn_message();
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction", "Location","northeast")
            else
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction", "Location","northeast")                
            end
        end
        
        function reset_kto(app)
            app.p1Knob_1.Value = app.Ktof_default(1);
            app.p1Ktof = app.Ktof_default(1);
            app.p2Knob_1.Value = app.Ktof_default(2);
            app.p2Ktof = app.Ktof_default(2);
            app.p3Knob.Value = app.Ktof_default(3);
            app.p3Ktof = app.Ktof_default(3);
            app.p4Knob.Value = app.Ktof_default(4);
            app.p4Ktof = app.Ktof_default(4);
            app.p5Knob.Value = app.Ktof_default(5);
            app.p5Ktof = app.Ktof_default(5);
            app.p6Knob.Value = app.Ktof_default(6);
            app.p6Ktof = app.Ktof_default(6);
            app.p7Knob.Value = app.Ktof_default(7);
            app.p7Ktof = app.Ktof_default(7);
            app.p8Knob.Value = app.Ktof_default(8);
            app.p8Ktof = app.Ktof_default(8);
            app.p9Knob.Value = app.Ktof_default(9);
            app.p9Ktof = app.Ktof_default(9);
            app.p10Knob_1.Value = app.Ktof_default(10);
            app.p10Ktof = app.Ktof_default(10);
            app.p11Knob_1.Value = app.Ktof_default(11);
            app.p11Ktof = app.Ktof_default(11);
            app.p12Knob_1.Value = app.Ktof_default(12);
            app.p12Ktof = app.Ktof_default(12);
            app.GKtoKnob.Value = app.Ktof_default(13);
            app.GKtof = app.Ktof_default(13);    
        end
        
        function update_sum_current(app, old_current_set, new_current_set)
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.yksum_set(:,i) - old_current_set(:,i) + new_current_set(:,i);
            end
        end
    end
    
    methods (Access = public)      
        function update_kto_dialog(app, param)
            % update knobs
            app.p1Knob_1.Value = param(1);
            app.p2Knob_1.Value = param(2);
            app.p3Knob_1.Value = param(3);
            app.p4Knob_1.Value = param(4);
            app.p5Knob_1.Value = param(5);
            app.p6Knob_1.Value = param(6);
            app.p7Knob_1.Value = param(7);
            app.p8Knob_1.Value = param(8);
            app.p9Knob_1.Value = param(9);
            app.p10Knob_1.Value = param(10);
            app.p11Knob_1.Value = param(11);
            app.p12Knob_1.Value = param(12);
            app.p13Knob_1.Value = param(13);
            app.p14Knob_1.Value = param(14);
            app.fKtoKnob.Value = param(15);
            app.GKtoKnob.Value = param(16);
            app.GKtopKnob.Value = param(17);
            
            % update dialog box
            app.p1Kto = param(1);
            app.p2Kto = param(2);
            app.p3Kto = param(3);
            app.p4Kto = param(4);
            app.p5Kto = param(5);
            app.p6Kto = param(6);
            app.p7Kto = param(7);
            app.p8Kto = param(8);
            app.p9Kto = param(9);
            app.p10Kto = param(10);
            app.p11Kto = param(11);
            app.p12Kto = param(12);
            app.p13Kto = param(13);
            app.p14Kto = param(14);
            app.fKto = param(15);
            app.GKto = param(16);
            app.GKtop = param(17);
            
            % generate new IKtof and update plots
            new_ykto_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_ykto_set);
            app.ykto_set = new_ykto_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % assign default parameter values Ktof
            app.Kto_default(1) = app.p1Kto;
            app.Kto_default(2) = app.p2Kto;
            app.Kto_default(3) = app.p3Kto;
            app.Kto_default(4) = app.p4Kto;
            app.Kto_default(5) = app.p5Kto;
            app.Kto_default(6) = app.p6Kto;
            app.Kto_default(7) = app.p7Kto;
            app.Kto_default(8) = app.p8Kto;
            app.Kto_default(9) = app.p9Kto;
            app.Kto_default(10) = app.p10Kto;
            app.Kto_default(11) = app.p11Kto;
            app.Kto_default(12) = app.p12Kto;
            app.Kto_default(13) = app.p13Kto;
            app.Kto_default(14) = app.p14Kto;
            app.Kto_default(15) = app.fKto;
            app.Kto_default(16) = app.GKto;
            app.Kto_default(17) = app.GKtop;
            
            % assign default parameter values Kss
            app.Kss_default(1) = 22.5;
            app.Kss_default(2) = 7.7;

            % generate time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);  
            
            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % plot default voltage clamp protocol
            plot_protocol(app);
            
            % generate & plot IKsum
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);

            app.yksum_set = app.ykto_set;
            plot_sum_currents_allvolts(app);
            
            % plot individual K+ currents
            app.volt_idx = 1;
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {874, 874};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {495, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.KtoDialogApp)
            delete(app.WarnApp)
            delete(app)
        end

        % Button pushed function: ProtocolVisualizationUpdateButton
        function ProtocolVisualizationUpdateButtonPushed(app, event)
            % update time space
            [app.hold_t, app.pulse_t, app.t] = gen_time_space(app);
            
            % update voltages
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % update protocl visualization
            plot_protocol(app);

            % update and plot currents with new protocol
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
%             app.yktos_set = gen_currents_over_volts(app, @gen_IKtos);
%             app.yk1_set = gen_currents_over_volts(app, @gen_IK1);
%             app.yks_set = gen_currents_over_volts(app, @gen_IKs);
%             app.ykur_set = gen_currents_over_volts(app, @gen_IKur);
%             app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            app.yksum_set = app.ykto_set;
            
            app.volt_idx = 1;
            app.SelectVoltageDropDown.Value = volt_dd_items(1);
            plot_currents_onevolt(app, app.volt_idx, false);
            plot_sum_currents_allvolts(app);
        end

        % Button pushed function: DatauploadButton
        function DatauploadButtonPushed(app, event)
            [file,path] = uigetfile(["*.csv";"*.xlsx"], "File Selector");
            exp_data = table2array(readtable(fullfile(path, file)));
                        
            app.exp_t = exp_data(:,1);
            app.exp_current_set = exp_data(:,2:end);

            % meta info
            app.exp_idx = true;
            [~, app.exp_num_volts] = size(app.exp_current_set);
            
            plot(app.UIAxesBottomLeft, app.exp_t, app.exp_current_set(:,1))
            hold(app.UIAxesBottomLeft, "on")
            for i=2:app.exp_num_volts
                plot(app.UIAxesBottomLeft, app.exp_t, app.exp_current_set(:,i))
            end
            hold(app.UIAxesBottomLeft, "off")
            axis(app.UIAxesBottomLeft, "tight")
            
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Value changed function: SelectVoltageDropDown
        function SelectVoltageDropDownValueChanged(app, event)
            value = app.SelectVoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_idx = find(app.volts == volt);
            
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Button pushed function: HighlightShowEquationsButton
        function HighlightShowEquationsButton_1Pushed(app, event)
            % hightlight IKto (index 1)
            plot_currents_onevolt(app, app.volt_idx, 1);
            
            % open IKto dialog
            app.KtoDialogApp = IKto_dialog(app);
        end

        % Callback function
        function ShowEquationsButton_7Pushed(app, event)
            % IKtos
            plot_currents_onevolt(app, app.volt_idx, 6);
            app.KtosDialogApp = IKtos_dialog(app);
        end

        % Callback function
        function HightlightButton_2Pushed(app, event)
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function
        function HightlightButton_3Pushed(app, event)
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Button pushed function: HighlightShowEquationsButton_4
        function HighlightShowEquationsButton_4Pushed(app, event)
            % IKur
            plot_currents_onevolt(app, app.volt_idx, 4);
            app.KurDialogApp = IKur_dialog(app);
        end

        % Button pushed function: HightlightButton_5
        function HightlightButton_5Pushed(app, event)
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Button pushed function: ResetButton
        function ResetButton_1Pushed(app, event)
            reset_ktof(app);
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);            
        end

        % Callback function
        function ResetButton_2Pushed(app, event)
            reset_k1(app);
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function
        function ResetButton_3Pushed(app, event)
            reset_ks(app);
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);            
            app.yks_set = new_yks_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            reset_kur(app);
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Button pushed function: ResetButton_5
        function ResetButton_5Pushed(app, event)
            reset_kss(app);
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Callback function
        function ResetButton_7Pushed(app, event)
            reset_ktos(app);
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Callback function
        function GKtoKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtoKnob.Value = changingValue;
            app.GKtof = changingValue;          
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_1.Value = changingValue;
            app.p1Ktof = changingValue;          
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p2KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_1.Value = changingValue;
            app.p2Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p3KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob.Value = changingValue;
            app.p3Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p4KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob.Value = changingValue;
            app.p4Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p5KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob.Value = changingValue;
            app.p5Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p6KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob.Value = changingValue;
            app.p6Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p7KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob.Value = changingValue;
            app.p7Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p8KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob.Value = changingValue;
            app.p8Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p9KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob.Value = changingValue;
            app.p9Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p10KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob_1.Value = changingValue;
            app.p10Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p11KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob_1.Value = changingValue;
            app.p11Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function p12KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p12Knob_1.Value = changingValue;
            app.p12Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.ykto_set, new_yktof_set);
            app.ykto_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function
        function KoKnobValueChanging(app, event)
            changingValue = event.Value;
            app.KoKnob.Value = changingValue;
            app.KoEditField.Value = changingValue;
            app.Koc1EditField.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function
        function p1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_2.Value = changingValue;
            app.p1EditField_2.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function
        function p2Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_1.Value = changingValue;
            app.p2EditField_2.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function
        function p3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_2.Value = changingValue;
            app.p3EditField_2.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function
        function GKsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKsKnob.Value = changingValue;
            app.GKsEditField.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function
        function p1Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_3.Value = changingValue;
            app.p1EditField_3.Value = changingValue;
            app.p1c1EditField_3.Value = changingValue;
            app.p1c2EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function
        function p2Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_3.Value = changingValue;
            app.p2EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function
        function p3Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_3.Value = changingValue;
            app.p3EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function
        function p4Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_3.Value = changingValue;
            app.p4EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function
        function p5Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_3.Value = changingValue;
            app.p5EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function
        function GKurKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKurKnob.Value = changingValue;
            app.GKur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p1Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_4.Value = changingValue;
            app.p1Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p2Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_4.Value = changingValue;
            app.p2Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p3Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_4.Value = changingValue;
            app.p3Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p4Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_4.Value = changingValue;
            app.p4Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p5Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_4.Value = changingValue;
            app.p5Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p6Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_4.Value = changingValue;
            app.p6Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p7Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_4.Value = changingValue;
            app.p7Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p8Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_4.Value = changingValue;
            app.p8Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function p9Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_4.Value = changingValue;
            app.p9Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function
        function GKssKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKssKnob.Value = changingValue;
            app.GKssEditField.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Callback function
        function p1Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_5.Value = changingValue;
            app.p1EditField_5.Value = changingValue;
            app.p1c1EditField_3.Value = changingValue;
            app.p1c2EditField_3.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);        
        end

        % Callback function
        function p2Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_5.Value = changingValue;
            app.p2EditField_5.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);          
        end

        % Callback function
        function p3Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_5.Value = changingValue;
            app.p3EditField_5.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Callback function
        function p4Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_5.Value = changingValue;
            app.p4EditField_5.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);           
        end

        % Callback function
        function p5Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_5.Value = changingValue;
            app.p5EditField_5.Value = changingValue;

            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);          
        end

        % Callback function
        function GKtosKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtosKnob.Value = changingValue;
            app.GKtos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Callback function
        function p1Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_7.Value = changingValue;
            app.p1Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);           
        end

        % Callback function
        function p2Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_7.Value = changingValue;
            app.p2Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Callback function
        function p3Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_7.Value = changingValue;
            app.p3Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Callback function
        function p4Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_7.Value = changingValue;
            app.p4Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);          
        end

        % Callback function
        function p5Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_7.Value = changingValue;
            app.p5Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);         
        end

        % Callback function
        function p6Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_7.Value = changingValue;
            app.p6Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);          
        end

        % Callback function
        function p7Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_7.Value = changingValue;
            app.p7Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);            
        end

        % Callback function
        function p8Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_7.Value = changingValue;
            app.p8Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);          
        end

        % Callback function
        function p9Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_7.Value = changingValue;
            app.p9Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ProtocolTab = uitab(app.TabGroup);
            app.ProtocolTab.Title = 'Protocol';
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingtimemsEditField.Value = 100;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampingvoltageminmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltageminmVEditFieldLabel.Text = 'Clamping voltage min (mV)';
            app.ClampingvoltageminmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltageminmVEditField.Value = -50;
            app.ClampingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingtimemsEditFieldLabel.Text = 'Clamping time (ms)';
            app.ClampingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingtimemsEditField.ValueDisplayFormat = '%.0f';
            app.ClampingtimemsEditField.Value = 4500;
            app.ProtocolVisualizationUpdateButton = uibutton(app.ProtocolTab, 'push');
            app.ProtocolVisualizationUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ProtocolVisualizationUpdateButtonPushed, true);
            app.ProtocolVisualizationUpdateButton.Text = 'Protocol Visualization & Update';
            app.NernstpotentialEkmVLabel = uilabel(app.ProtocolTab);
            app.NernstpotentialEkmVLabel.Text = 'Nernst potential Ek (mV)';
            app.NernstpotentialEkmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.NernstpotentialEkmVEditField.Value = -91.1;
            app.DatauploadButton = uibutton(app.ProtocolTab, 'push');
            app.DatauploadButton.ButtonPushedFcn = createCallbackFcn(app, @DatauploadButtonPushed, true);
            app.DatauploadButton.Text = 'Data upload';
            app.ClampingvoltagestepmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltagestepmVEditFieldLabel.Text = 'Clamping voltage step (mV)';
            app.ClampingvoltagestepmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltagestepmVEditField.Value = 10;
            app.ClampingvoltagemaxmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltagemaxmVEditFieldLabel.Text = 'Clamping voltage max (mV)';
            app.ClampingvoltagemaxmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltagemaxmVEditField.Value = 50;
            app.ModelCalibrationButton = uibutton(app.ProtocolTab, 'push');
            app.ModelCalibrationButton.Text = 'Model Calibration';
            app.UIAxesTopLeft = uiaxes(app.ProtocolTab);
            title(app.UIAxesTopLeft, 'Protocol')
            xlabel(app.UIAxesTopLeft, 'Time (ms)')
            ylabel(app.UIAxesTopLeft, 'Voltage (mV)')
            zlabel(app.UIAxesTopLeft, 'Z')
            app.UIAxesBottomLeft = uiaxes(app.ProtocolTab);
            title(app.UIAxesBottomLeft, 'Experimental Data')
            xlabel(app.UIAxesBottomLeft, 'Time (ms)')
            ylabel(app.UIAxesBottomLeft, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomLeft, 'Z')
            app.IKtoKv42Tab = uitab(app.TabGroup);
            app.IKtoKv42Tab.Title = 'IKto (Kv 4.2)';
            app.TextArea_1 = uitextarea(app.IKtoKv42Tab);
            app.TextArea_1.Value = {'IKto: Transient outward K+ current '};
            app.HighlightShowEquationsButton = uibutton(app.IKtoKv42Tab, 'push');
            app.HighlightShowEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @HighlightShowEquationsButton_1Pushed, true);
            app.HighlightShowEquationsButton.Text = 'Highlight & Show Equations';
            app.ResetButton = uibutton(app.IKtoKv42Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_1Pushed, true);
            app.ResetButton.Text = 'Reset';
            app.fKtoKnobLabel = uilabel(app.IKtoKv42Tab);
            app.fKtoKnobLabel.Text = 'fKto';
            app.fKtoKnob = uiknob(app.IKtoKv42Tab, 'continuous');
            app.fKtoKnob.Value = 0.2087704319;
            app.GKtoKnobLabel = uilabel(app.IKtoKv42Tab);
            app.GKtoKnobLabel.Text = 'GKto';
            app.GKtoKnob = uiknob(app.IKtoKv42Tab, 'continuous');
            app.GKtoKnob.Value = 0.14067;
            app.GKtopKnobLabel = uilabel(app.IKtoKv42Tab);
            app.GKtopKnobLabel.Text = 'GKto,p';
            app.GKtopKnob = uiknob(app.IKtoKv42Tab, 'continuous');
            app.GKtopKnob.Value = 0.387;
            app.p1Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p1Knob_6Label.Text = 'p1';
            app.p1Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p1Knob_1.Value = 33;
            app.p2Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p2Knob_6Label.Text = 'p2';
            app.p2Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p2Knob_1.Value = 15.5;
            app.p3Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p3Knob_6Label.Text = 'p3';
            app.p3Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p3Knob_1.Value = 20;
            app.p4Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p4Knob_6Label.Text = 'p4';
            app.p4Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p4Knob_1.Value = 16;
            app.p5Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p5Knob_6Label.Text = 'p5';
            app.p5Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p5Knob_1.Value = 8;
            app.p6Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p6Knob_5Label.Text = 'p6';
            app.p6Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p6Knob_1.Value = 7;
            app.p7Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p7Knob_5Label.Text = 'p7';
            app.p7Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p7Knob_1.Value = 0.03577;
            app.p8Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p8Knob_5Label.Text = 'p8';
            app.p8Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p8Knob_1.Value = 0.06237;
            app.p9Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p9Knob_5Label.Text = 'p9';
            app.p9Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p9Knob_1.Value = 0.18064;
            app.p10KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p10KnobLabel.Text = 'p10';
            app.p10Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p10Knob_1.Value = 0.3956;
            app.p11KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p11KnobLabel.Text = 'p11';
            app.p11Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p11Knob_1.Value = 0.000152;
            app.p12KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p12KnobLabel.Text = 'p12';
            app.p12Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p12Knob_1.Value = 0.067083;
            app.p13KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p13KnobLabel.Text = 'p13';
            app.p13Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p13Knob_1.Value = 0.00095;
            app.p14KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p14KnobLabel.Text = 'p14';
            app.p14Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p14Knob_1.Value = 0.051335;
            app.IKslow1Kv15Tab = uitab(app.TabGroup);
            app.IKslow1Kv15Tab.Title = 'IKslow1 (Kv 1.5)';
            app.TextArea_4 = uitextarea(app.IKslow1Kv15Tab);
            app.TextArea_4.Value = {'IKslow1: Rapidly activating, slowly inactivating K+ current'};
            app.HighlightShowEquationsButton_4 = uibutton(app.IKslow1Kv15Tab, 'push');
            app.HighlightShowEquationsButton_4.ButtonPushedFcn = createCallbackFcn(app, @HighlightShowEquationsButton_4Pushed, true);
            app.HighlightShowEquationsButton_4.Text = 'Highlight & Show Equations';
            app.ResetButton_4 = uibutton(app.IKslow1Kv15Tab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.IKslow2Kv21Tab = uitab(app.TabGroup);
            app.IKslow2Kv21Tab.Title = 'IKslow2 (Kv 2.1)';
            app.IKssTab = uitab(app.TabGroup);
            app.IKssTab.Title = 'IKss';
            app.ResetButton_5 = uibutton(app.IKssTab, 'push');
            app.ResetButton_5.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_5Pushed, true);
            app.ResetButton_5.Text = 'Reset';
            app.HightlightButton_5 = uibutton(app.IKssTab, 'push');
            app.HightlightButton_5.ButtonPushedFcn = createCallbackFcn(app, @HightlightButton_5Pushed, true);
            app.HightlightButton_5.Text = 'Hightlight';
            app.RightPanel = uipanel(app.GridLayout);
            app.SelectVoltageDropDownLabel = uilabel(app.RightPanel);
            app.SelectVoltageDropDownLabel.Text = 'Select Voltage';
            app.SelectVoltageDropDown = uidropdown(app.RightPanel);
            app.SelectVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVoltageDropDownValueChanged, true);
            app.UIAxesMiddleRight = uiaxes(app.RightPanel);
            title(app.UIAxesMiddleRight, 'Individual K^{+} Currents of Selected Voltage')
            xlabel(app.UIAxesMiddleRight, 'Time (ms)')
            ylabel(app.UIAxesMiddleRight, 'Current (pA/pF)')
            zlabel(app.UIAxesMiddleRight, 'Z')
            app.UIAxesTopRight = uiaxes(app.RightPanel);
            title(app.UIAxesTopRight, 'Sum of K^{+} Currents')
            xlabel(app.UIAxesTopRight, 'Time (ms)')
            ylabel(app.UIAxesTopRight, 'Current (pA/pF)')
            zlabel(app.UIAxesTopRight, 'Z')
            app.UIAxesBottomRight = uiaxes(app.RightPanel);
            title(app.UIAxesBottomRight, 'Sum of K^{+} Current of Selected Voltage')
            xlabel(app.UIAxesBottomRight, 'Time (ms)')
            ylabel(app.UIAxesBottomRight, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomRight, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end